classdef BlockchainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        StartButton               matlab.ui.control.Button
        DataEditFieldLabel        matlab.ui.control.Label
        DataEditField             matlab.ui.control.EditField
        MineRateEditFieldLabel_2  matlab.ui.control.Label
        MineRate                  matlab.ui.control.NumericEditField
        MineBlockButton           matlab.ui.control.Button
        ConnectionsTab            matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        PeerApiEditFieldLabel     matlab.ui.control.Label
        PeerApiEditField          matlab.ui.control.EditField
        AddPeerButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        blockchain
         peers 
        
       opts % Description
        minerate % Description
    end

    
    methods (Access = private)
        
        function results = updatechain2sheets(app)
            block=struct(app.blockchain.chain(end));
            
            for i=1:length(app.peers)
                 userInfo = webwrite(app.peers(i), block, app.opts);
                
            end
           
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        
            
            app.opts = weboptions('MediaType','application/json');
            
       
            app.blockchain = Blockchain(double(app.minerate));
            app.updatechain2sheets();


        end

        % Button pushed function: AddPeerButton
        function AddPeerButtonPushed(app, event)
            app.peers = [app.peers convertCharsToStrings(app.PeerApiEditField.Value)];
            app.UITable.Data = [app.UITable.Data; cellstr(((app.peers(end))))];
            app.PeerApiEditField.Value="";
        end

        % Callback function
        function MineRateValueChanged(app, event)
            app.minerate = app.MineRate.Value;
            
        end

        % Button pushed function: MineBlockButton
        function MineBlockButtonPushed(app, event)
            app.blockchain = app.blockchain.add_block(app.DataEditField.Value );
            app.updatechain2sheets();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DataEditFieldLabel = uilabel(app.Tab);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.Tab, 'text');
            app.MineRateEditFieldLabel_2 = uilabel(app.Tab);
            app.MineRateEditFieldLabel_2.Text = 'MineRate';
            app.MineRate = uieditfield(app.Tab, 'numeric');
            app.MineBlockButton = uibutton(app.Tab, 'push');
            app.MineBlockButton.ButtonPushedFcn = createCallbackFcn(app, @MineBlockButtonPushed, true);
            app.MineBlockButton.Text = 'Mine Block';
            app.ConnectionsTab = uitab(app.TabGroup);
            app.ConnectionsTab.Title = 'Connections';
            app.UITable = uitable(app.ConnectionsTab);
            app.UITable.ColumnName = {'PeerApiEndPoint'};
            app.UITable.RowName = {};
            app.PeerApiEditFieldLabel = uilabel(app.ConnectionsTab);
            app.PeerApiEditFieldLabel.Text = 'PeerApi';
            app.PeerApiEditField = uieditfield(app.ConnectionsTab, 'text');
            app.AddPeerButton = uibutton(app.ConnectionsTab, 'push');
            app.AddPeerButton.ButtonPushedFcn = createCallbackFcn(app, @AddPeerButtonPushed, true);
            app.AddPeerButton.Text = 'AddPeer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlockchainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end